classdef GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        axes1                matlab.ui.control.UIAxes
        pushbutton1          matlab.ui.control.Button
        pushbutton2          matlab.ui.control.Button
        EuroLabel            matlab.ui.control.Label
        EuroTextArea         matlab.ui.control.TextArea
        CentTextAreaLabel    matlab.ui.control.Label
        CentTextArea         matlab.ui.control.TextArea
        CentLabel            matlab.ui.control.Label
        CentTextArea_3       matlab.ui.control.TextArea
        CentLabel_3          matlab.ui.control.Label
        CentTextArea_5       matlab.ui.control.TextArea
        EuroTextArea_2Label  matlab.ui.control.Label
        EuroTextArea_2       matlab.ui.control.TextArea
        CentTextArea_2Label  matlab.ui.control.Label
        CentTextArea_2       matlab.ui.control.TextArea
        CentLabel_2          matlab.ui.control.Label
        CentTextArea_4       matlab.ui.control.TextArea
        CentLabel_4          matlab.ui.control.Label
        CentTextArea_6       matlab.ui.control.TextArea
        ValueTextAreaLabel   matlab.ui.control.Label
        ValueTextArea        matlab.ui.control.TextArea
        EuroLabel_2          matlab.ui.control.Label
    end


    properties (Access = private)
            
            Img = [];
    end


    methods (Access = private)

        % Code that executes after component creation
        function DemoGUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to DemoGUI (see VARARGIN)
%             
%             % Choose default command line output for DemoGUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes DemoGUI wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             % Initialize variables and GUI items
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             global myImage;
%             myImage = [];
%             set(app.edit1,'String','0');
%             set(app.edit2,'String','0');
%             set(app.edit3,'String','0');
%             set(app.axes1,'YTick',[]);
%             set(app.axes1,'XTick',[]);
               
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Load image after clicking button
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [FileName,PathName] = uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
                      '*.*','All Files' },'Select image files only!');
            global myImage;
            myImage = imread(fullfile(PathName,FileName));
            %Convertir les images RVB en valeurs de niveaux de gris
            Image = rgb2gray(myImage);
            %Compresser la taille de l'image
            app.Img = imresize(myImage,[800 NaN]);
            imshow(app.Img,'Parent', app.axes1);
            app.Img = imresize(Image,[800 NaN]);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Do processing calculation in loaded image
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            global myImage;
            if(~isempty(myImage))
                %Appliquez une transformation morphologique pour supprimer du Fond.
                s=strel('disk',50);
                test_tophat=imclose(app.Img,s)-app.Img;
                img_gray=255-test_tophat;
                %Utilisez l'opérateur Sobel pour la détection des bords.
                img_edge =edge(img_gray,'sobel');
                
                
                se=strel('disk',8);
                img_close = imclose(img_edge, se);
                
                
                %Remplir les trous
                img_fill = imfill(img_close, 'holes');
                Img_clean = bwareaopen(img_fill, 500);   
                [B, L] = bwboundaries(Img_clean);
                stats = regionprops(L,app.Img(:,:,1),...
                    'Area','Centroid','Orientation','EquivDiameter','MeanIntensity');
                
                coinCentroids = [];
                coinArea = [];
                coinEquivDiameter=[];
                %Déterminez le centre, le diamètre et la surface de la pièce.
                for k = 1:length(B)
                    boundary = B{k};
                    delta_sq = diff(boundary).^2;
                    perimeter = sum(sqrt(sum(delta_sq,2)));
                    area = stats(k).Area;
                    metric = 4*pi*area/perimeter^2;
                    metric_string = sprintf('%2.2f',metric);
                    angle_string = sprintf('%2.2f',stats(k).Orientation);
                    centroid = stats(k).Centroid;
                
                        coinCentroids = [coinCentroids; centroid];
                        coinArea=[coinArea; area];
                        coinEquivDiameter=[coinEquivDiameter; stats(k).EquivDiameter];
                
                    %plot(app.axes1, centroid(1),centroid(2),'r+');
                
                end
                
                E2 = 0;
                E1 = 0;
                C50 = 0;
                C20 = 0;
                C10 = 0;
                C5 = 0;
                C2 = 0;
                C1 = 0;
                
                %Comptez le nombre de pièces différentes
                
                for k = 1:length(coinArea)
                    if((stats(k).Area)>45000&&(stats(k).Area)<48000)
                        E2=E2+1;
                    elseif((stats(k).Area)>40000&&(stats(k).Area)<43000)
                        C50=C50+1;
                    elseif((stats(k).Area)>37000&&(stats(k).Area)<40000)
                        E1=E1+1;
                    elseif((stats(k).Area)>32800&&(stats(k).Area)<36000)
                        C20=C20+1;
                    elseif((stats(k).Area)>30000&&(stats(k).Area)<32800)
                        C5=C5+1;
                    elseif((stats(k).Area)>26500&&(stats(k).Area)<29000)
                        C10=C10+1;
                    elseif((stats(k).Area)>24000&&(stats(k).Area)<26500)
                        C2=C2+1;
                    elseif((stats(k).Area)>18200&&(stats(k).Area)<20600)
                        C1=C1+1;
                    
                    end

                end
                app.EuroTextArea.Value = num2str(E2);
                app.EuroTextArea_2.Value = num2str(E1);
                app.CentTextArea.Value = num2str(C50);
                app.CentTextArea_2.Value = num2str(C20);
                app.CentTextArea_3.Value = num2str(C10);
                app.CentTextArea_4.Value = num2str(C5);
                app.CentTextArea_5.Value = num2str(C2);
                app.CentTextArea_6.Value = num2str(C1);
                
                Total=E2*2+E1+C50*0.5+C20*0.2+C10*0.1+C5*0.05+C2*0.02+C1*0.01;
                %Calculez la valeur des pièces
                app.ValueTextArea.Value = num2str(Total);
            end
        end

        % Value changed function: ValueTextArea
        function ValueTextAreaValueChanged(app, event)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Coin Calculator';
            app.axes1 = uiaxes(app.figure1);
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Load Image';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'Calculate';
            app.EuroLabel = uilabel(app.figure1);
            app.EuroLabel.Text = '2Euro';
            app.EuroTextArea = uitextarea(app.figure1);
            app.EuroTextArea.Editable = 'off';
            app.CentTextAreaLabel = uilabel(app.figure1);
            app.CentTextAreaLabel.Text = '50Cent';
            app.CentTextArea = uitextarea(app.figure1);
            app.CentTextArea.Editable = 'off';
            app.CentLabel = uilabel(app.figure1);
            app.CentLabel.Text = '10Cent';
            app.CentTextArea_3 = uitextarea(app.figure1);
            app.CentTextArea_3.Editable = 'off';
            app.CentLabel_3 = uilabel(app.figure1);
            app.CentLabel_3.Text = '2Cent';
            app.CentTextArea_5 = uitextarea(app.figure1);
            app.CentTextArea_5.Editable = 'off';
            app.EuroTextArea_2Label = uilabel(app.figure1);
            app.EuroTextArea_2Label.Text = '1Euro';
            app.EuroTextArea_2 = uitextarea(app.figure1);
            app.EuroTextArea_2.Editable = 'off';
            app.CentTextArea_2Label = uilabel(app.figure1);
            app.CentTextArea_2Label.Text = '20Cent';
            app.CentTextArea_2 = uitextarea(app.figure1);
            app.CentTextArea_2.Editable = 'off';
            app.CentLabel_2 = uilabel(app.figure1);
            app.CentLabel_2.Text = '5Cent';
            app.CentTextArea_4 = uitextarea(app.figure1);
            app.CentTextArea_4.Editable = 'off';
            app.CentLabel_4 = uilabel(app.figure1);
            app.CentLabel_4.Text = '1Cent';
            app.CentTextArea_6 = uitextarea(app.figure1);
            app.CentTextArea_6.Editable = 'off';
            app.ValueTextAreaLabel = uilabel(app.figure1);
            app.ValueTextAreaLabel.Text = 'Value';
            app.ValueTextArea = uitextarea(app.figure1);
            app.ValueTextArea.ValueChangedFcn = createCallbackFcn(app, @ValueTextAreaValueChanged, true);
            app.ValueTextArea.Editable = 'off';
            app.EuroLabel_2 = uilabel(app.figure1);
            app.EuroLabel_2.Text = 'Euro';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @DemoGUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end